classdef MeyveGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ButonPaneli           matlab.ui.container.Panel
        DurumLamp             matlab.ui.control.Lamp
        DurumLampLabel        matlab.ui.control.Label
        KAMERAYIBALATButton   matlab.ui.control.StateButton
        RESMYKLETESTETButton  matlab.ui.control.Button
        Gauge                 matlab.ui.control.Gauge
        GaugeLabel            matlab.ui.control.Label
        SONUBEKLENYORLabel    matlab.ui.control.Label
        Image                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        Net % Modeli saklayacağımız yer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Model dosyasını yükle
if ~exist('trainedNet', 'var')
    try
        loadedData = load('FinalProjeModelim.mat');
        app.Net = loadedData.trainedNet; % Modeli app içine kaydet
    catch
        uialert(app.UIFigure, 'Model dosyası bulunamadı!', 'Hata');
    end
else
    app.Net = evalin('base', 'trainedNet');
end
        end

        % Button pushed function: RESMYKLETESTETButton
        function RESMYKLETESTETButtonPushed(app, event)
    % 1. Dosya Seçme Penceresini Aç
    [dosya, yol] = uigetfile({'*.jpg;*.jpeg;*.png', 'Resim Dosyaları (*.jpg, *.png)'});
    
    if isequal(dosya, 0)
        return; % Kullanıcı iptal ederse çık
    end
    
    % 2. Resmi Oku ve Hazırla
    dosyaYolu = fullfile(yol, dosya);
    img = imread(dosyaYolu);
    
    % Boyutlandırma (Yapay zeka için 227x227)
    imgResized = imresize(img, [227 227]);
    
    % 3. TAHMİN ET
    [label, scores] = classify(app.Net, imgResized);
    
    % --- BURASI ÖNEMLİ: GÜVEN ORANINI HESAPLA ---
    confidence = max(scores) * 100; 
    
    % --- İBREYİ GÜNCELLE (GAUGE) ---
    app.Gauge.Value = confidence; % İbre doğrudan o değere fırlasın
    
    etiketStr = char(label);
    
    % 4. İsimlendirme ve Renk (Kamera koduyla aynı mantık)
    urun = 'Tanimlanamadi';
    renk = 'yellow';
    durum = '';
    
    hamIsim = lower(etiketStr);
    
    % Durum Kontrolü
    if contains(hamIsim, 'fresh'), durum='TAZE'; renk='green';
    elseif contains(hamIsim, 'rotten'), durum='CURUK'; renk='red';
    end
    
    % Ürün İsmi Kontrolü
    if contains(hamIsim, 'apple'), urun='ELMA';
    elseif contains(hamIsim, 'banana'), urun='MUZ';
    elseif contains(hamIsim, 'orange'), urun='PORTAKAL';
    elseif contains(hamIsim, 'tomato'), urun='DOMATES';
    elseif contains(hamIsim, 'potato'), urun='PATATES';
    elseif contains(hamIsim, 'cucumber'), urun='SALATALIK';
    elseif contains(hamIsim, 'capsicum'), urun='BIBER';
    elseif contains(hamIsim, 'okra'), urun='BAMYA';
    elseif contains(hamIsim, 'bitter'), urun='KUDRET NARI';
    end
    
    % 5. Sonuçları Ekrana Bas
    app.Image.ImageSource = img; % Orijinal resmi göster
    app.SONUBEKLENYORLabel.Text = sprintf('%s %s (%%%0.1f)', durum, urun, confidence);
    
    % Yazı Rengi Ayarı
    if strcmp(renk, 'green')
        app.SONUBEKLENYORLabel.FontColor = [0 0.5 0]; % Koyu Yeşil
    elseif strcmp(renk, 'red')
        app.SONUBEKLENYORLabel.FontColor = [1 0 0];   % Kırmızı
    else
        app.SONUBEKLENYORLabel.FontColor = [0 0 0];   % Siyah
    end

        end

        % Value changed function: KAMERAYIBALATButton
        function KAMERAYIBALATButtonValueChanged(app, event)
    % 1. BUTON DURUMUNU KONTROL ET
    value = app.KAMERAYIBALATButton.Value;
    
    if value == 1 % --- KAMERA AÇIK ---
        
        % Görünümü değiştir
        app.KAMERAYIBALATButton.Text = 'DURDUR';
        app.KAMERAYIBALATButton.BackgroundColor = [1 0 0]; % Kırmızı
        app.SONUBEKLENYORLabel.Text = 'Kamera başlatılıyor...';
        drawnow; 
        
        % 2. KAMERAYI BAŞLAT
        cam = [];
        try
            camList = webcamlist;
            if isempty(camList), error('Kamera bulunamadı!'); end
            cam = webcam(1); 
        catch hatam
            uialert(app.UIFigure, ['Kamera Hatası: ' hatam.message], 'Hata');
            app.KAMERAYIBALATButton.Value = 0;
            return; 
        end
        
        % STABİLİZASYON DEĞİŞKENİ 
        ortalamaGuven = 0; 
        app.DurumLamp.Color = 'green'; % Yeşil yak
        % 3. DÖNGÜ
        while app.KAMERAYIBALATButton.Value == 1 
            try
                % Fotoğraf Çek
                img = snapshot(cam);
                
                % Kırpma
                [h, w, ~] = size(img);
                try
                    cropRect = [(w-300)/2, (h-300)/2, 300, 300];
                    if w < 300 || h < 300
                         imgCrop = img; 
                    else
                         imgCrop = imcrop(img, cropRect);
                    end
                catch
                    imgCrop = img; 
                end
                
                % Tahmin
                imgResized = imresize(imgCrop, [227 227]);
                [label, scores] = classify(app.Net, imgResized);
                
                % --- MATEMATİKSEL DÜZELTME VE GAUGE AYARI ---
                anlikGuven = max(scores) * 100;
                
                % Titremeyi önleyen formül (Eskinin %70'i + Yeninin %30'u)
                ortalamaGuven = (ortalamaGuven * 0.7) + (anlikGuven * 0.3);
                
                etiketStr = char(label);
                
                % --- İSİMLENDİRME ---
                urun = 'Tanimlanamadi';
                renk = 'yellow';
                
                hamIsim = lower(etiketStr);
                if contains(hamIsim, 'fresh'), durum='TAZE'; renk='green';
                elseif contains(hamIsim, 'rotten'), durum='CURUK'; renk='red';
                else, durum=''; 
                end
                
                if contains(hamIsim, 'apple'), urun='ELMA';
                elseif contains(hamIsim, 'banana'), urun='MUZ';
                elseif contains(hamIsim, 'orange'), urun='PORTAKAL';
                elseif contains(hamIsim, 'tomato'), urun='DOMATES';
                elseif contains(hamIsim, 'potato'), urun='PATATES';
                elseif contains(hamIsim, 'cucumber'), urun='SALATALIK';
                elseif contains(hamIsim, 'capsicum'), urun='BIBER';
                elseif contains(hamIsim, 'okra'), urun='BAMYA';
                elseif contains(hamIsim, 'bitter'), urun='KUDRET NARI';
                end
                
                % --- GÖRSELLEŞTİRME ---
                if w >= 300 && h >= 300
                    imgGoster = insertShape(img, 'Rectangle', cropRect, 'LineWidth', 5, 'Color', renk);
                else
                    imgGoster = img;
                end
                
                app.Image.ImageSource = imgGoster;
                
                % Yazıyı Güncelle (Ortalama güveni kullanıyoruz)
                app.SONUBEKLENYORLabel.Text = sprintf('%s %s (%%%0.1f)', durum, urun, ortalamaGuven);
                
                % --- GAUGE (İBRE) AYARI ---
                % Gauge değerini güncelle
                app.Gauge.Value = ortalamaGuven; 
                
                % Renk Ayarı
                if strcmp(renk, 'green')
                    app.SONUBEKLENYORLabel.FontColor = [0 0.5 0];
                else
                    app.SONUBEKLENYORLabel.FontColor = [1 0 0];
                end
                
                drawnow; 
                
            catch loopHata
                disp(['Döngü hatası: ' loopHata.message]);
                break; 
            end
        end
        app.DurumLamp.Color = 'red';   % Kırmızı yak
        % 4. KAPANIŞ
        if exist('cam', 'var'), clear cam; end
        app.KAMERAYIBALATButton.Text = 'KAMERAYI BAŞLAT';
        app.KAMERAYIBALATButton.BackgroundColor = [0.96 0.96 0.96]; 
        app.SONUBEKLENYORLabel.Text = 'Kamera Durduruldu.';
        app.SONUBEKLENYORLabel.FontColor = [0 0 0];
        app.Gauge.Value = 0; % Kapanınca ibreyi sıfırla
        
    else 
        % Buton kapalı
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.SONUBEKLENYORLabel = uilabel(app.UIFigure);
            app.SONUBEKLENYORLabel.Text = 'SONUÇ BEKLENİYOR...';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.ButonPaneli = uipanel(app.UIFigure);
            app.ButonPaneli.Title = 'Buton Paneli';
            app.RESMYKLETESTETButton = uibutton(app.ButonPaneli, 'push');
            app.RESMYKLETESTETButton.ButtonPushedFcn = createCallbackFcn(app, @RESMYKLETESTETButtonPushed, true);
            app.RESMYKLETESTETButton.Text = 'RESİM YÜKLE & TEST ET';
            app.RESMYKLETESTETButton.Icon = fullfile(pathToMLAPP, 'folder-98462_640.png');
            app.KAMERAYIBALATButton = uibutton(app.ButonPaneli, 'state');
            app.KAMERAYIBALATButton.ValueChangedFcn = createCallbackFcn(app, @KAMERAYIBALATButtonValueChanged, true);
            app.KAMERAYIBALATButton.Text = '       KAMERAYI BAŞLAT';
            app.KAMERAYIBALATButton.Icon = fullfile(pathToMLAPP, 'camera-2112207_640.png');
            app.DurumLampLabel = uilabel(app.ButonPaneli);
            app.DurumLampLabel.Text = 'Durum';
            app.DurumLamp = uilamp(app.ButonPaneli);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MeyveGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end